classdef MyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu_ImageProcess              matlab.ui.container.Menu
        Menu_imageImport               matlab.ui.container.Menu
        Menu_imageSave                 matlab.ui.container.Menu
        Panel_VideoFunction            matlab.ui.container.Panel
        Label_VideoEndTime             matlab.ui.control.Label
        Label_VideoStartTime           matlab.ui.control.Label
        Slider_VideoProgress           matlab.ui.control.Slider
        Button_VideoLinear             matlab.ui.control.Button
        Button_VideoToImage            matlab.ui.control.Button
        Button_videoPlay               matlab.ui.control.Button
        Button_Segmentation            matlab.ui.control.Button
        Button_import_recongnizevideo  matlab.ui.control.Button
        Button_recongnizeInformation   matlab.ui.control.Button
        Panel_imageRecongnize          matlab.ui.container.Panel
        UIAxes_VideoPlay               matlab.ui.control.UIAxes
        Panel_imageContrast            matlab.ui.container.Panel
        UIAxes_imageCotrast            matlab.ui.control.UIAxes
        Panel_imageFunction            matlab.ui.container.Panel
        Button_saveImage               matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_importImage             matlab.ui.control.Button
        DropDown_imageContour          matlab.ui.control.DropDown
        Label_6                        matlab.ui.control.Label
        DropDown_edgeDetection         matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        DropDown_imageEnhance          matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        DropDown_preFilter             matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        DropDown_analogNoise           matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        DropDown_FourierTransform      matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        Panel_imageShow                matlab.ui.container.Panel
        UIAxes_imageShow               matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Filename=[]%建立私有属性，属于app内部属性
        Pathname=[]%针对图片需要合成图片名称
        VideoFilename=[]
        VideoPathname=[]
        VideoToImageFolder=[]%视频转图片，图片保存路径
        ImageToVideoFolder=[]%将图片转成视频的文件夹路径
        test_videopath=[]%处理后的视频存放的路径
    end
    
    methods (Access = private)
        %% 将视频文件转化为图片文件
        function VideoToImage(app,foldername)
             % 将MP4视频转为图片函数
             % 将视频转化为照片
             if isempty(app.VideoFilename)
                  return;
             else
                  videopath=fullfile(app.VideoPathname,app.VideoFilename);
                  videoReader=VideoReader(videopath);%创建VideoReader对象
                  numFrames=videoReader.NumFrames;%检测视频总帧数
                  outputFolder=foldername;%设置保存视频照片的文件夹路径
                  if ~exist(outputFolder,'dir')%如果没有相应的文件夹产生，则生成一个新的文件夹
                        mkdir(outputFolder);
                  end
                  frameInterval=1;
                  handler_waitbar=waitbar(0,'图片生成中....','WindowStyle','normal');
                  try
                      for frameIdx =1:frameInterval:numFrames
                           frame=read(videoReader,frameIdx);%读取当前帧的照片
                           %显示进度
                           waitbar(frameIdx/numFrames,handler_waitbar,['已完成' num2str(round(frameIdx/numFrames*100)) '%']);
                           pause(0.01);
                           outputfilename=sprintf('frame%03d.jpg',frameIdx);%给每一张图片排序存储命名
                           outpath=fullfile(outputFolder,outputfilename);
                           imwrite(frame,outpath);
                      end
                      close(handler_waitbar);%关闭信息框
                  catch
                        errordlg('运行错误，请重新运行!','运行错误');
                        return 
                  end
              end
        end

        
        %% 用于播放视频，未处理的视频
        function Video_Play(app)
               %创建VideoReader对象进行操作
               if isempty(app.VideoFilename)
                   return;
               else
                   %先路径后文件名
                   video_path=fullfile(app.VideoPathname,app.VideoFilename);
                   video_reader=VideoReader(video_path);%读取视频文件
                   %显示该视频当前总时间
                   EndTime=video_reader.NumFrames/video_reader.FrameRate;
                   End_Minute=floor(EndTime/60);
                   End_Second=round(mod(EndTime,60));
                   EndTime=sprintf('%02d:%02d',End_Minute,End_Second);
                   app.Label_VideoEndTime.Text=EndTime;
                   %进行播放显示
                   try
                       for frameIdx=1: video_reader.NumFrames
                           frame=read(video_reader,frameIdx);
                           imshow(frame,'Parent',app.UIAxes_VideoPlay);
                           %在进度条上显示进度浮块
                           app.Slider_VideoProgress.Value=frameIdx/video_reader.NumFrames;
                           %显示当前时间以及总的时间
                           CurrentTime=frameIdx/video_reader.FrameRate;%获取已播放的时间
                           Current_Minute=floor(CurrentTime/60);
                           Current_Second=round(mod(CurrentTime,60));
                           app.Label_VideoStartTime.Text='';%清空字符
                           CurrentTime=sprintf('%02d:%02d',Current_Minute,Current_Second);
                           app.Label_VideoStartTime.Text=CurrentTime;
                           %暂停一段时间,按照标准帧率进行播放
                           pause(1/60);
                       end
                   catch
                       return
                   end
               end
        end


        %% 实例分割函数，将每一帧图片进行语义分割并存储在ImageToVideoFolder中
        function Segmented_Image(app)
            if isempty(app.VideoFilename)
                return;
            else 
                %% 视频实例分割
                %导入模型进行训练
                data = load("deeplabv3plusResnet18CamVid.mat");%下载训练集
                net = data.net;%准备训练网络
                %生成完整的待测试视频文件路径
                videopath=fullfile(app.VideoPathname,app.VideoFilename);
                video_read=VideoReader(videopath);
                numFrames=video_read.NumFrames;
                %创建一个VideoWrite类型数据，储存处理后的图片
                app.test_videopath=fullfile(app.VideoPathname,'SegmentatedVideo.AVI');
                video_segmented=VideoWriter(app.test_videopath);
                open(video_segmented);
                %创建一个进度条显示
                wait_segmented=waitbar(0,'实例分割中....','WindowStyle','normal');
                try%放在出现程序错误，产生警告
                    for i =1:numFrames
                         %显示进度条
                         waitbar(i/numFrames,wait_segmented,['已完成' num2str(round(i/numFrames*100)) '%']);
                         frame=read(video_read,i);
                         %将处理图片放置待合成的文
                         inputSize = net.Layers(1).InputSize;
                         frame = imresize(frame,inputSize(1:2));
                         C_frame = semanticseg(frame,net);
                         %生成camp函数
                         cmap = [
                             128 128 128   % 天空
                             128 0 0       % 建筑
                             192 192 192   % Pole
                             128 64 128    % Road
                             60 40 222     % Pavement
                             128 128 0     % Tree
                             192 128 128   % SignSymbol
                             64 64 128     % Fence
                             64 0 128      % Car
                             64 64 0       % Pedestrian
                             0 128 192     % Bicyclist
                             ];
                         % Normalize between [0 1]归一化处理
                         cmap = cmap ./ 255;
                         %返回处理的图片
                         Transformed_Pic = labeloverlay(frame,C_frame,'Colormap',cmap,'Transparency',0.4);
                         %将处理后的图片写入video_segmented数据对象中
                         writeVideo(video_segmented,Transformed_Pic);
                         pause(0.01);
                     end
                     close(video_segmented);
                     close(wait_segmented);
                catch
                    errordlg('运行错误，请重新运行并删除已产生垃圾文件!','运行错误');
                    return 
                end
                 %% 进行播放显示,替换为已经处理完毕的文件进行显示
                 %mp4格式的文件无法复用路径，因而使用.avi格式
                 video_segmented=VideoReader(num2str(app.test_videopath));
                 %显示该视频当前总时间
                 EndTime=video_segmented.NumFrames/video_segmented.FrameRate;
                 End_Minute=floor(EndTime/60);
                 End_Second=round(mod(EndTime,60));
                 EndTime=sprintf('%02d:%02d',End_Minute,End_Second);
                 app.Label_VideoEndTime.Text=EndTime;
                 %进行播放显示
                 try%防止播放错误
                     for i=1:video_segmented.NumFrames
                         frame=read(video_segmented,i);
                         imshow(frame,'Parent',app.UIAxes_VideoPlay);
                         %在进度条上显示进度浮块
                         app.Slider_VideoProgress.Value=i/video_segmented.NumFrames;
                         %显示当前时间以及总的时间
                         CurrentTime=i/video_segmented.FrameRate;%获取已播放的时间
                         Current_Minute=floor(CurrentTime/60);
                         Current_Second=round(mod(CurrentTime,60));
                         app.Label_VideoStartTime.Text='';%清空字符
                         CurrentTime=sprintf('%02d:%02d',Current_Minute,Current_Second);
                         app.Label_VideoStartTime.Text=CurrentTime;
                         %暂停一小段时间
                         pause(1/60);
                     end
                 catch
                     errordlg('播放错误，请重新播放!','播放错误');
                     return 
                 end
            end          
        end
        

        %% 用于将视频线性化处理
        function VideoLinear(app)
                 if ~isempty(app.VideoFilename)
                     %% 视频线性处理
                     %读取视频文件，创建VideoReader对象
                     videopath=fullfile(app.VideoPathname,app.VideoFilename);
                     Origin_video=VideoReader(videopath);
                     Origin_numframes=Origin_video.NumFrames;%获取视频帧数
                     %创建空视频对象
                     app.test_videopath=fullfile(app.VideoPathname,'LinearizedVideo.AVI');
                     Linearize_video=VideoWriter(app.test_videopath);
                     open(Linearize_video);%打开空的视频对象
                     %创建waitbar函数句柄
                     wait_linear=waitbar(0,'线性处理中....','WindowStyle','normal');
                     try
                         for i = 1:Origin_numframes
                             frame=read(Origin_video,i);
                             %将图像线性化
                             frame=edge(rgb2gray(frame),'approxcanny');
                             writeVideo(Linearize_video,double(frame));%将图片写入对象中
                             %显示进度条
                             waitbar(i/Origin_numframes,wait_linear,...
                                 ['已完成' num2str(round(i/Origin_numframes*100)) '%']);
                             pause(0.01);%暂停0.01秒
                         end
                         close(wait_linear);%关闭进度条
                         close(Linearize_video);%关闭视频对象
                     catch
                         errordlg('运行错误，请重新运行并删除已产生垃圾文件!','运行错误');
                        return 
                     end
                     %% 视频播放
                     Linearize_video=VideoReader(app.test_videopath);%获取视频对象
                     %显示该视频当前总时间
                     EndTime=Linearize_video.NumFrames/Linearize_video.FrameRate;
                     End_Minute=floor(EndTime/60);
                     End_Second=round(mod(EndTime,60));
                     EndTime=sprintf('%02d:%02d',End_Minute,End_Second);
                     app.Label_VideoEndTime.Text=EndTime;
                     %进行播放显示
                     try%防止播放错误，设置警告标志
                         for i =1:Linearize_video.NumFrames
                            frame=read(Linearize_video,i);%读取对象中的图片成员
                            imshow(frame,'Parent',app.UIAxes_VideoPlay);
                            %在进度条上显示进度浮块
                            app.Slider_VideoProgress.Value=i/Linearize_video.NumFrames;
                            %显示当前时间以及总的时间
                            CurrentTime=i/Linearize_video.FrameRate;%获取已播放的时间
                            Current_Minute=floor(CurrentTime/60);
                            Current_Second=round(mod(CurrentTime,60));
                            app.Label_VideoStartTime.Text='';%清空字符
                            CurrentTime=sprintf('%02d:%02d',Current_Minute,Current_Second);
                            app.Label_VideoStartTime.Text=CurrentTime;
                            pause(1/60);%暂停
                         end
                     catch
                         errordlg('播放错误，请重新播放!','播放错误');
                            return 
                     end
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_importImage
        function Button_importImagePushed(app, event)
            [app.Filename,app.Pathname]=uigetfile({'*.jpg;*.png;*.bmp;*.gif','All Image Files';...  
                                        '*.jpg','JPG Files';...  
                                        '*.png','PNG Files';...  
                                        '*.bmp','BMP Files';...  
                                        '*.gif','GIF Files';...  
                                        '*.*','All Files' },'选择一个图像');%打开文件选择窗口
            if isequal(app.Filename,0) || isempty(app.Filename)%检测是否选择了文件
                return 
            end
            %构建完整文件路径
            Fullfilename=fullfile(app.Pathname,app.Filename);
            %原始导入的图像文件
            Origin_Image=imread(Fullfilename);
            %展示在图像展示区
            imshow(Origin_Image,'parent',app.UIAxes_imageShow);
        end

        % Button pushed function: Button_saveImage
        function Button_saveImagePushed(app, event)
            %获取当前显示图像的句柄
            imageHandle=get(app.UIAxes_imageCotrast,'Children');
            %如果图像存在，则保存
            if ~isempty(imageHandle)
                Save_Image=getimage(app.UIAxes_imageCotrast);
                %显示保存对话框
                [app.Filename,app.Pathname]=uiputfile({'*.png';'*.jpg';'*.bmp';'*.gif';'*.*'},'保存图像');
                Fullfilename=fullfile(app.Pathname,app.Filename);
                imwrite(Save_Image,Fullfilename);
                msgbox('图像已保存！');
            end
        end

        % Value changed function: DropDown_FourierTransform
        function DropDown_FourierTransformValueChanged(app, event)
            value = app.DropDown_FourierTransform.Value;
            %获取图像展示区的图片数据
            Origin_Image=getimage(app.UIAxes_imageShow);
            switch value
                case '傅里叶变换'
                    Transformed_Image=ifftn(fft2(Origin_Image));
                    imshow(Transformed_Image,'parent',app.UIAxes_imageCotrast);
                case '腐蚀变换'
                    Transformed_Image=rgb2gray(Origin_Image);%将图像灰度处理
                    se = offsetstrel('ball',5,5);%创建一个非平面 offsetstrel 对象
                    Transformed_Image=imerode(Transformed_Image,se);
                    imshow(Transformed_Image,'parent',app.UIAxes_imageCotrast);
                case '膨胀变换'
                    Transformed_Image=rgb2gray(Origin_Image);%将图像灰度处理
                    se = offsetstrel('ball',5,5);%创建一个非平面 offsetstrel 对象
                    Transformed_Image=imdilate(Transformed_Image,se);
                    imshow(Transformed_Image,'parent',app.UIAxes_imageCotrast);
            end
        end

        % Value changed function: DropDown_analogNoise
        function DropDown_analogNoiseValueChanged(app, event)
            value = app.DropDown_analogNoise.Value;
            %获取图像展示区的图片数据
            Origin_Image=getimage(app.UIAxes_imageShow);
            switch value
                case 'gaussian'
                    Transformed_Image=imnoise(Origin_Image,'gaussian',0,0.05);
                    imshow(Transformed_Image,'Parent',app.UIAxes_imageCotrast);
                case 'localvar'
                    localvar=0.01*ones(size(Origin_Image));
                    % 在某些区域增加方差，例如中心区域  
                    center_row_start = round(size(Origin_Image, 1) / 4);  
                    center_row_end = round(3 * size(Origin_Image, 1) / 4);  
                    center_col_start = round(size(Origin_Image, 2) / 4);  
                    center_col_end = round(3 * size(Origin_Image, 2) / 4);  
                    localvar(center_row_start:center_row_end, center_col_start:center_col_end) = 0.2;
                    Transformed_Image= imnoise(Origin_Image,'localvar',localvar);
                    imshow(Transformed_Image,'parent',app.UIAxes_imageCotrast);
                case 'poisson'
                    Transformed_Image=imnoise(Origin_Image,'poisson');
                    imshow(Transformed_Image,'Parent',app.UIAxes_imageCotrast);
                case 'salt & pepper'
                    Transformed_Image=imnoise(Origin_Image,'salt & pepper',0.2);
                    imshow(Transformed_Image,'Parent',app.UIAxes_imageCotrast);
                case 'speckle'
                    Transformed_Image=imnoise(Origin_Image,'speckle',0.2);
                    imshow(Transformed_Image,'Parent',app.UIAxes_imageCotrast);
             end
        end

        % Value changed function: DropDown_preFilter
        function DropDown_preFilterValueChanged(app, event)
            value = app.DropDown_preFilter.Value;
            Origin_Image=getimage(app.UIAxes_imageShow);
            switch value%创建预定义的二维滤滤波器，并对图片进行滤波
                case 'gaussian'
                    h=fspecial('gaussian',[1000,1000]);
                case 'sobel'
                    h=fspecial('sobel');
                case 'laplacian'
                    h=fspecial('laplacian',0.5);
                case 'log'
                    h=fspecial('log',[50,50]);
                case 'average'
                    h=fspecial('average',[30,30]);
                case 'disk'
                    h=fspecial('disk',5);
                case 'motion'
                    h=fspecial('motion',20,90);%相机线性移动，移动方向
                case 'prewitt'
                    h=fspecial('prewitt');
            end
            Transformed_Image=imfilter(Origin_Image,h,'circular','full','conv');
            imshow(Transformed_Image,'parent',app.UIAxes_imageCotrast);
        end

        % Value changed function: DropDown_imageEnhance
        function DropDown_imageEnhanceValueChanged(app, event)
            value = app.DropDown_imageEnhance.Value;
            Origin_Image=getimage(app.UIAxes_imageShow);
            switch value
                case '直方图均化'
                    Transformed_Image=histeq(Origin_Image);
                case '对比度调整'
                    Transformed_Image=imadjust(Origin_Image,[0.25,0.78],[0.1,0.9]);
                case '对数变换'
                    Transformed_Image=log10(double(Origin_Image));
                    Transformed_Image=histeq(Transformed_Image);%进行数据增强
                case '卷积滤波'
                    h=[1,2,1;0,0,0;-1,-2,-1];%Sobel算子
                    Origin_Image=im2gray(Origin_Image);
                    Transformed_Image=filter2(h,Origin_Image,'same');
                case '线性滤波'
                    h=ones(3,3)/9;
                    Origin_Image=im2gray(Origin_Image);
                    Transformed_Image=conv2(Origin_Image,h);
                    Transformed_Image=uint8(Transformed_Image);%将图片数据转变为uint8类型
                case '中值滤波'
                    Origin_Image=im2gray(Origin_Image);
                    Transformed_Image=medfilt2(Origin_Image);
                case '锐化处理'
                    h=[0,1,0;1,-4,0;0,1,0];%拉氏算子
                    Origin_Image=im2gray(Origin_Image);
                    double_Image=double(Origin_Image);
                    Transformed_Image=conv2(double_Image,h,'full');
            end
            imshow(Transformed_Image,'parent',app.UIAxes_imageCotrast);
        end

        % Value changed function: DropDown_edgeDetection
        function DropDown_edgeDetectionValueChanged(app, event)
            value = app.DropDown_edgeDetection.Value;
            Origin_Image=getimage(app.UIAxes_imageShow);
            Origin_Image=rgb2gray(Origin_Image);
            switch value
                case 'Sobel'
                    Transformed_Image=edge(Origin_Image,"sobel");
                case 'Prewitt'
                    Transformed_Image=edge(Origin_Image,'prewitt');
                case 'Roberts'
                    Transformed_Image=edge(Origin_Image,'roberts');
                case 'log'
                    Transformed_Image=edge(Origin_Image,'log');
                case 'zerocross'
                    Transformed_Image=edge(Origin_Image,'zerocross');
                case 'Canny'
                    Transformed_Image=edge(Origin_Image,'canny');
                case 'approxcanny'
                    Transformed_Image=edge(Origin_Image,'approxcanny');
            end
            imshow(Transformed_Image,'parent',app.UIAxes_imageCotrast);
        end

        % Value changed function: DropDown_imageContour
        function DropDown_imageContourValueChanged(app, event)
            value = app.DropDown_imageContour.Value;
            Origin_Image=getimage(app.UIAxes_imageShow);
            Origin_Image=im2gray(Origin_Image);
            position=[0,0,400,374];
            hFig=figure;
            set(hFig,'Position',position);%保证imcontour生成的图像是在坐标去显示
            switch value
                case '程度100'
                    Transformed_Image=imcontour(Origin_Image,100);
                case '程度50'
                    Transformed_Image=imcontour(Origin_Image,50);
                case '程度10'
                    Transformed_Image=imcontour(Origin_Image,10);
                case '程度5'
                    Transformed_Image=imcontour(Origin_Image,5);
            end
            imshow(Transformed_Image,'Parent',app.UIAxes_imageCotrast);
        end

        % Menu selected function: Menu_imageImport
        function Menu_imageImportSelected(app, event)
            Button_importImagePushed(app, event);
        end

        % Menu selected function: Menu_imageSave
        function Menu_imageSaveSelected(app, event)
            Button_saveImagePushed(app, event);
        end

        % Button pushed function: Button_import_recongnizevideo
        function Button_import_recongnizevideoPushed(app, event)
             [app.VideoFilename,app.VideoPathname]=uigetfile({'*.MP4';'*.M4V';'*.MJ2';'*.MPG';'*.WMV';'*.AVI';'*.*'},'选择一个文件视频');%打开文件选择窗口
            if isequal(app.VideoFilename,0) || isempty(app.VideoFilename)%检测是否选择了文件
                return 
            end
            %主要是获取视频文件路径
            Video_Play(app);
        end

        % Button pushed function: Button_videoPlay
        function Button_videoPlayPushed(app, event)
            Video_Play(app);
        end

        % Button pushed function: Button_Segmentation
        function Button_SegmentationPushed(app, event)
            %将视频转化为实例分割后的视频    
            Segmented_Image(app);
        end

        % Callback function
        function Button_VideoPlay_ShowPushed(app, event)
            Video_Play(app);
        end

        % Button pushed function: Button_VideoToImage
        function Button_VideoToImagePushed(app, event)
            %在MyApp的路径下，生成VideoToImage文件
            app.VideoToImageFolder=fullfile(app.VideoPathname,'VideoToImage');
            VideoToImage(app,app.VideoToImageFolder);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            msgbox(['导入图片,选择功能,生成图片再进行保存即可' ...
                '注意每次导入的图片只能处理一次，若要多步处理可以将生成的图片保存再导入处理。']);
        end

        % Button pushed function: Button_recongnizeInformation
        function Button_recongnizeInformationPushed(app, event)
            msgbox(['导入视频，生成图片,实例分割；注意生成的视频或图片位于导入视频的同一文件夹下；'... 
                '实例分割、线性处理得到的视频若要再次播放，需要到相应的文件夹下导入视频进行播放；' ...
                '生成图像、实例分割、线性处理得到的视频与导入视频处在同一文件夹下。']);
        end

        % Button pushed function: Button_VideoLinear
        function Button_VideoLinearPushed(app, event)
            VideoLinear(app);
        end

        % Value changing function: Slider_VideoProgress
        function Slider_VideoProgressValueChanging(app, event)
             changingValue = event.Value;
             return 
        end

        % Value changed function: Slider_VideoProgress
        function Slider_VideoProgressValueChanged(app, event)
            value = app.Slider_VideoProgress.Value;
            return;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu_ImageProcess = uimenu(app.UIFigure);
            app.Menu_ImageProcess.Text = '图像处理';
            app.Menu_imageImport = uimenu(app.Menu_ImageProcess);
            app.Menu_imageImport.MenuSelectedFcn = createCallbackFcn(app, @Menu_imageImportSelected, true);
            app.Menu_imageImport.Text = '导入图像';
            app.Menu_imageSave = uimenu(app.Menu_ImageProcess);
            app.Menu_imageSave.MenuSelectedFcn = createCallbackFcn(app, @Menu_imageSaveSelected, true);
            app.Menu_imageSave.Text = '保存图像';
            app.Panel_imageShow = uipanel(app.UIFigure);
            app.Panel_imageShow.Title = '图像展示';
            app.UIAxes_imageShow = uiaxes(app.Panel_imageShow);
            app.UIAxes_imageShow.XTick = [];
            app.UIAxes_imageShow.YTick = [];
            app.Panel_imageFunction = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel_imageFunction);
            app.Label.Text = '图像变换';
            app.DropDown_FourierTransform = uidropdown(app.Panel_imageFunction);
            app.DropDown_FourierTransform.Items = {'傅里叶变换', '腐蚀变换', '膨胀变换'};
            app.DropDown_FourierTransform.ValueChangedFcn = createCallbackFcn(app, @DropDown_FourierTransformValueChanged, true);
            app.DropDown_FourierTransform.Value = '傅里叶变换';
            app.Label_2 = uilabel(app.Panel_imageFunction);
            app.Label_2.Text = '模拟噪声';
            app.DropDown_analogNoise = uidropdown(app.Panel_imageFunction);
            app.DropDown_analogNoise.Items = {'gaussian', 'localvar', 'poisson', 'salt & pepper', 'speckle'};
            app.DropDown_analogNoise.ValueChangedFcn = createCallbackFcn(app, @DropDown_analogNoiseValueChanged, true);
            app.DropDown_analogNoise.Value = 'gaussian';
            app.Label_3 = uilabel(app.Panel_imageFunction);
            app.Label_3.Text = '预设滤波';
            app.DropDown_preFilter = uidropdown(app.Panel_imageFunction);
            app.DropDown_preFilter.Items = {'gaussian', 'sobel', 'laplacian', 'log', 'average', 'disk', 'motion', 'prewitt'};
            app.DropDown_preFilter.ValueChangedFcn = createCallbackFcn(app, @DropDown_preFilterValueChanged, true);
            app.DropDown_preFilter.Value = 'gaussian';
            app.Label_4 = uilabel(app.Panel_imageFunction);
            app.Label_4.Text = '图像增强';
            app.DropDown_imageEnhance = uidropdown(app.Panel_imageFunction);
            app.DropDown_imageEnhance.Items = {'直方图均化', '对比度调整', '对数变换', '卷积滤波', '线性滤波', '中值滤波', '锐化处理'};
            app.DropDown_imageEnhance.ValueChangedFcn = createCallbackFcn(app, @DropDown_imageEnhanceValueChanged, true);
            app.DropDown_imageEnhance.Value = '直方图均化';
            app.Label_5 = uilabel(app.Panel_imageFunction);
            app.Label_5.Text = '边缘检测';
            app.DropDown_edgeDetection = uidropdown(app.Panel_imageFunction);
            app.DropDown_edgeDetection.Items = {'Sobel', 'Prewitt', 'Roberts', 'log', 'zerocross', 'Canny', 'approxcanny'};
            app.DropDown_edgeDetection.ValueChangedFcn = createCallbackFcn(app, @DropDown_edgeDetectionValueChanged, true);
            app.DropDown_edgeDetection.Value = 'Sobel';
            app.Label_6 = uilabel(app.Panel_imageFunction);
            app.Label_6.Text = '流线形化';
            app.DropDown_imageContour = uidropdown(app.Panel_imageFunction);
            app.DropDown_imageContour.Items = {'程度100', '程度50', '程度10', '程度5'};
            app.DropDown_imageContour.ValueChangedFcn = createCallbackFcn(app, @DropDown_imageContourValueChanged, true);
            app.DropDown_imageContour.Value = '程度10';
            app.Button_importImage = uibutton(app.Panel_imageFunction, 'push');
            app.Button_importImage.ButtonPushedFcn = createCallbackFcn(app, @Button_importImagePushed, true);
            app.Button_importImage.Text = '导入图像';
            app.Button = uibutton(app.Panel_imageFunction, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '信息';
            app.Button_saveImage = uibutton(app.Panel_imageFunction, 'push');
            app.Button_saveImage.ButtonPushedFcn = createCallbackFcn(app, @Button_saveImagePushed, true);
            app.Button_saveImage.Text = '保存图像';
            app.Panel_imageContrast = uipanel(app.UIFigure);
            app.Panel_imageContrast.Title = '图像对比';
            app.UIAxes_imageCotrast = uiaxes(app.Panel_imageContrast);
            app.UIAxes_imageCotrast.XTick = [];
            app.UIAxes_imageCotrast.YTick = [];
            app.Panel_imageRecongnize = uipanel(app.UIFigure);
            app.Panel_imageRecongnize.Title = '视频处理';
            app.UIAxes_VideoPlay = uiaxes(app.Panel_imageRecongnize);
            app.UIAxes_VideoPlay.XTick = [];
            app.UIAxes_VideoPlay.YTick = [];
            app.Panel_VideoFunction = uipanel(app.UIFigure);
            app.Button_recongnizeInformation = uibutton(app.Panel_VideoFunction, 'push');
            app.Button_recongnizeInformation.ButtonPushedFcn = createCallbackFcn(app, @Button_recongnizeInformationPushed, true);
            app.Button_recongnizeInformation.Text = '信息';
            app.Button_import_recongnizevideo = uibutton(app.Panel_VideoFunction, 'push');
            app.Button_import_recongnizevideo.ButtonPushedFcn = createCallbackFcn(app, @Button_import_recongnizevideoPushed, true);
            app.Button_import_recongnizevideo.Text = '导入视频';
            app.Button_Segmentation = uibutton(app.Panel_VideoFunction, 'push');
            app.Button_Segmentation.ButtonPushedFcn = createCallbackFcn(app, @Button_SegmentationPushed, true);
            app.Button_Segmentation.Text = '实例分割';
            app.Button_videoPlay = uibutton(app.Panel_VideoFunction, 'push');
            app.Button_videoPlay.ButtonPushedFcn = createCallbackFcn(app, @Button_videoPlayPushed, true);
            app.Button_videoPlay.Text = '播放视频';
            app.Button_VideoToImage = uibutton(app.Panel_VideoFunction, 'push');
            app.Button_VideoToImage.ButtonPushedFcn = createCallbackFcn(app, @Button_VideoToImagePushed, true);
            app.Button_VideoToImage.Text = '生成图片';
            app.Button_VideoLinear = uibutton(app.Panel_VideoFunction, 'push');
            app.Button_VideoLinear.ButtonPushedFcn = createCallbackFcn(app, @Button_VideoLinearPushed, true);
            app.Button_VideoLinear.Text = '线性处理';
            app.Slider_VideoProgress = uislider(app.Panel_VideoFunction);
            app.Slider_VideoProgress.Limits = [0 1];
            app.Slider_VideoProgress.MajorTicks = [];
            app.Slider_VideoProgress.ValueChangedFcn = createCallbackFcn(app, @Slider_VideoProgressValueChanged, true);
            app.Slider_VideoProgress.ValueChangingFcn = createCallbackFcn(app, @Slider_VideoProgressValueChanging, true);
            app.Slider_VideoProgress.MinorTicks = [];
            app.Label_VideoStartTime = uilabel(app.Panel_VideoFunction);
            app.Label_VideoStartTime.Text = '00:00';
            app.Label_VideoEndTime = uilabel(app.Panel_VideoFunction);
            app.Label_VideoEndTime.Text = '00:00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end